classdef holo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HolowavesUIFigure              matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainviewTab                    matlab.ui.container.Tab
        ImageRight                     matlab.ui.control.Image
        ImageLeft                      matlab.ui.control.Image
        CurrentFilePanel               matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        ImagerenderingPanel            matlab.ui.container.Panel
        savelamp                       matlab.ui.control.Lamp
        AutofocusButton                matlab.ui.control.Button
        SavepreviewButton              matlab.ui.control.Button
        renderLamp                     matlab.ui.control.Lamp
        lowfrequencyCheckBox           matlab.ui.control.CheckBox
        RenderpreviewButton            matlab.ui.control.Button
        batchsizeEditField             matlab.ui.control.NumericEditField
        batchsizeEditFieldLabel        matlab.ui.control.Label
        num_batches                    matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        zirisEditField                 matlab.ui.control.NumericEditField
        zretinaEditField               matlab.ui.control.NumericEditField
        positioninfileSlider           matlab.ui.control.Slider
        positioninfileSliderLabel      matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        max_PCAEditField               matlab.ui.control.NumericEditField
        maxEditFieldLabel              matlab.ui.control.Label
        min_PCAEditField               matlab.ui.control.NumericEditField
        minEditFieldLabel              matlab.ui.control.Label
        timetransformDropDown          matlab.ui.control.DropDown
        timetransformDropDownLabel     matlab.ui.control.Label
        blurEditField                  matlab.ui.control.NumericEditField
        gwLabel                        matlab.ui.control.Label
        ImageChoiceDropDown            matlab.ui.control.DropDown
        compositef3EditField           matlab.ui.control.NumericEditField
        compositef3EditFieldLabel      matlab.ui.control.Label
        compositef2EditField           matlab.ui.control.NumericEditField
        compositef2EditFieldLabel      matlab.ui.control.Label
        compositef1EditField           matlab.ui.control.NumericEditField
        compositef1EditFieldLabel      matlab.ui.control.Label
        f2EditField                    matlab.ui.control.NumericEditField
        f2EditFieldLabel               matlab.ui.control.Label
        f1EditField                    matlab.ui.control.NumericEditField
        f1EditFieldLabel               matlab.ui.control.Label
        AberrationcompensationPanel    matlab.ui.container.Panel
        imagesubapsizeratioEditField   matlab.ui.control.NumericEditField
        imagesubapsizeratioEditFieldLabel  matlab.ui.control.Label
        subapnumpositionsEditField     matlab.ui.control.NumericEditField
        subapnumpositionsEditFieldLabel  matlab.ui.control.Label
        referenceimageDropDown         matlab.ui.control.DropDown
        referenceimageDropDownLabel    matlab.ui.control.Label
        ZernikeProjectionCheckBox      matlab.ui.control.CheckBox
        savecoefsCheckBox              matlab.ui.control.CheckBox
        rangeYEditField                matlab.ui.control.NumericEditField
        rangeYEditFieldLabel           matlab.ui.control.Label
        rangeZEditField                matlab.ui.control.NumericEditField
        rangeZEditFieldLabel           matlab.ui.control.Label
        volumesizeEditField            matlab.ui.control.NumericEditField
        volumesizeEditFieldLabel       matlab.ui.control.Label
        maxSubAp_PCAEditField_2        matlab.ui.control.NumericEditField
        maxEditField_2Label            matlab.ui.control.Label
        minSubAp_PCAEditField_2        matlab.ui.control.NumericEditField
        minEditField_2Label            matlab.ui.control.Label
        SubAp_PCACheckBox              matlab.ui.control.CheckBox
        PreviewLabel                   matlab.ui.control.Label
        masknumiterEditField           matlab.ui.control.NumericEditField
        masknumiterLabel               matlab.ui.control.Label
        maxconstraintEditField         matlab.ui.control.NumericEditField
        maxconstraintLabel             matlab.ui.control.Label
        zernikestolEditField           matlab.ui.control.NumericEditField
        toleranceLabel                 matlab.ui.control.Label
        optimizationzernikeranksEditField  matlab.ui.control.NumericEditField
        zernikeranksEditField_2Label   matlab.ui.control.Label
        IterativeoptimizationCheckBox  matlab.ui.control.CheckBox
        subaperturemarginEditField     matlab.ui.control.NumericEditField
        subaperturemarginEditFieldLabel  matlab.ui.control.Label
        shackhartmannzernikeranksEditField  matlab.ui.control.NumericEditField
        zernikeranksEditFieldLabel     matlab.ui.control.Label
        ShackHartmannCheckBox          matlab.ui.control.CheckBox
        Label_2                        matlab.ui.control.Label
        UIAxes_aberrationPreview       matlab.ui.control.UIAxes
        AdvancedProcessingPanel        matlab.ui.container.Panel
        SVDxCheckBox                   matlab.ui.control.CheckBox
        SVDx_SubApEditField            matlab.ui.control.NumericEditField
        SVDx_SubApEditFieldLabel       matlab.ui.control.Label
        xystrideEditField              matlab.ui.control.NumericEditField
        xystrideEditFieldLabel         matlab.ui.control.Label
        r1EditField                    matlab.ui.control.NumericEditField
        r1EditFieldLabel               matlab.ui.control.Label
        unitcellsinlatticeEditField    matlab.ui.control.NumericEditField
        unitcellsinlatticeEditFieldLabel  matlab.ui.control.Label
        nu2EditField                   matlab.ui.control.NumericEditField
        nu2EditFieldLabel              matlab.ui.control.Label
        nu1EditField                   matlab.ui.control.NumericEditField
        nu1EditFieldLabel              matlab.ui.control.Label
        phi2EditField                  matlab.ui.control.NumericEditField
        phi2EditFieldLabel             matlab.ui.control.Label
        phi1EditField                  matlab.ui.control.NumericEditField
        phi1EditFieldLabel             matlab.ui.control.Label
        LocalfilteringLabel            matlab.ui.control.Label
        temporalCheckBox               matlab.ui.control.CheckBox
        spatialCheckBox                matlab.ui.control.CheckBox
        SVDCheckBox                    matlab.ui.control.CheckBox
        FileselectionPanel             matlab.ui.container.Panel
        numworkersSpinner              matlab.ui.control.Spinner
        numworkersSpinnerLabel         matlab.ui.control.Label
        imageregistrationCheckBox      matlab.ui.control.CheckBox
        NotesTextArea                  matlab.ui.control.TextArea
        NotesTextAreaLabel             matlab.ui.control.Label
        SaveconfigButton               matlab.ui.control.Button
        spatialTransformationDropDown  matlab.ui.control.DropDown
        spatialtransformationLabel     matlab.ui.control.Label
        setUpDropDown                  matlab.ui.control.DropDown
        fsEditField                    matlab.ui.control.NumericEditField
        fsEditFieldLabel               matlab.ui.control.Label
        refbatchsizeEditField          matlab.ui.control.NumericEditField
        refbatchsizeEditFieldLabel     matlab.ui.control.Label
        DyEditField                    matlab.ui.control.NumericEditField
        DyEditFieldLabel               matlab.ui.control.Label
        DxEditField                    matlab.ui.control.NumericEditField
        DxEditFieldLabel               matlab.ui.control.Label
        ParallelismDropDown            matlab.ui.control.DropDown
        wavelengthEditField            matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel       matlab.ui.control.Label
        batchstrideEditField           matlab.ui.control.NumericEditField
        batchstrideEditFieldLabel      matlab.ui.control.Label
        LoadfileButton                 matlab.ui.control.Button
        VideorenderingPanel            matlab.ui.container.Panel
        iterativeregistrationCheckBox  matlab.ui.control.CheckBox
        saveconfigLamp                 matlab.ui.control.Lamp
        ApplytoallfilesCheckBox        matlab.ui.control.CheckBox
        VideorenderingButton           matlab.ui.control.Button
        FoldermanagementButton         matlab.ui.control.Button
        temporalfilterEditField        matlab.ui.control.NumericEditField
        temporalfilterCheckBox         matlab.ui.control.CheckBox
        phaseregistrationCheckBox      matlab.ui.control.CheckBox
        outputvideoDropDown            matlab.ui.control.DropDown
        outputvideoDropDownLabel       matlab.ui.control.Label
        lowmemoryCheckBox              matlab.ui.control.CheckBox
        rephasingCheckBox              matlab.ui.control.CheckBox
        saverawvideosCheckBox          matlab.ui.control.CheckBox
    end


    properties (Access = public)
        % skip callbacks if no file_M0 is loaded
        nb_cpu_cores

        file_loaded

        filename % current file_M0 loaded
        filepath
        extension % .raw or .cine
        interferogram_stream

        % data related to current displayed frame
        frame_batch

        % misc proxy data
        Nx
        Ny
        Fs
        z_reconstruction
        z_retina
        z_iris
        pix_width
        pix_height
        blur
        kernelAngularSpectrum
        kernelFresnel
        FH
        SH
        var_ImageTypeList = ImageTypeList();
        hologram
        phasePlane %aberration correction preview
        DX % x circshift for registration
        DY % y circshift for registration

        OCTdata = OCTdata();
        image_registration

        % misc
        cache % cache containing all parameters
        rephasing_data % data containing correction phase computed previously

        SubAp_PCA
        time_transform

        drawer_list = {}
    end

    properties (Access = private)
        DialogApp % Dialog box app
        poolobj
    end

    methods (Access = private)

        function GPUpreview = check_GPU_for_preview(app)
            GPUpreview = true; % by default
            % FIXME: handle and select best GPU if many available
            gpuDevice(1);
            available_mem = gpuDevice(1).AvailableMemory;
            % FIXME: find or make single/double precision flag
            predicted_FH_size = size(app.frame_batch, 1) * size(app.frame_batch, 2) * size(app.frame_batch, 3) * 16; % 8/16 for single/double complex scalars
            % FIXME: 0.7
            if predicted_FH_size > 0.7 * available_mem
                GPUpreview = false;
            end
            % FIXME : else : launch GPU cores for compute
            % parpool
        end

        function copy_data_to_front(app, data)
            % set gui parameters from cache
            try
                app.batchsizeEditField.Value = data.batch_size;
                app.max_PCAEditField.Limits = [0 double(app.batchsizeEditField.Value)];
            end
            % try
            %     app.numworkersSpinner.Value = data.nb_cpu_cores;
            % end
            try
                app.rangeZEditField.Value = data.OCTdata.range_z(end) - data.OCTdata.range_z(1);
                app.rangeYEditField.Value = data.OCTdata.range_y(end) - data.OCTdata.range_y(1);
            end
            try
                app.refbatchsizeEditField.Value = data.ref_batch_size;
            end
            try
                app.batchstrideEditField.Value = data.batch_stride;
            end
            try
                app.Switch.Value = data.z_switch;
            end
            try
                app.zretinaEditField.Value = data.z_retina;
            end
            try
                app.zirisEditField.Value = data.z_iris;
            end
            try
                app.time_transform = data.time_transform;
            end
            try
                app.blurEditField.Value = data.blur;
            end
            try
                app.ImageChoiceDropDown.Value = strrep(data.imageChoice, '_', ' ');
            end
            try
                app.timetransformDropDown.Value = app.time_transform.type;
            end
            try
                app.SVDCheckBox.Value = data.SVD;
            end
            try
                app.f1EditField.Value = data.time_transform.f1;
            end
            try
                app.f2EditField.Value = data.time_transform.f2;
            end
            try
                app.min_PCAEditField.Value = data.time_transform.min_PCA;
            end
            try
                app.max_PCAEditField.Value = data.time_transform.max_PCA;
            end
            try
                app.imageregistrationCheckBox.Value = data.registration;
            end
            try
                app.iterativeregistrationCheckBox.Value = data.iterativeregistration;
            end
            try
                app.wavelengthEditField.Value = data.wavelength;
            end
            try
                app.outputvideoDropDown.Value = strrep(data.output_videos, '_', ' ');
            end
            try
                app.lowmemoryCheckBox.Value = data.low_memory;
            end
            try
                app.rephasingCheckBox.Value = data.rephasing;
            end
            try
                app.NotesTextArea.Value = data.notes;
            end
            try
                app.positioninfileSlider.Value = data.position_in_file;
            end
            try
                app.EditField.Value = data.position_in_file;
            end
            try
                app.IterativeoptimizationCheckBox.Value = data.iterative_aberration_compensation;
            end
            try
                app.masknumiterEditField.Value = data.mask_num_iter;
            end
            %             app.zernikestolEditField.Value = data.low_order_zernikes;
            %             app.maxconstraintEditField.Value = data.low_order_max_constraint;
            try
                app.ShackHartmannCheckBox.Value = data.shack_hartmann_aberration_compensation;
            end
            try
                app.ZernikeProjectionCheckBox.Value = data.zernike_projection;
            end
            try
                app.referenceimageDropDown.Value = data.shack_hartmann_ref_image;
            end
            try
                app.shackhartmannzernikeranksEditField.Value = data.shack_hartmann_zernike_ranks;
            end
            try
                app.imagesubapsizeratioEditField.Value = data.image_subapertures_size_ratio;
            end
            try
                app.subapnumpositionsEditField.Value = data.num_subapertures_positions;
            end
            try
                app.subaperturemarginEditField.Value = data.subaperture_margin;
            end
            try
                app.SubAp_PCACheckBox.Value = data.SubAp_PCA;
            end
            try
                app.minSubAp_PCAEditField_2.Value = data.minSubAp_PCA;
            end
            try
                app.maxSubAp_PCAEditField_2.Value = data.maxSubAp_PCA;
            end
            try
                app.compositef1EditField.Value = data.color_f1;
            end
            try
                app.compositef2EditField.Value = data.color_f2;
            end
            try
                app.compositef3EditField.Value = data.color_f3;
            end
            try
                app.lowfrequencyCheckBox.Value = data.low_frequency;
            end
            try
                app.saverawvideosCheckBox.Value = data.save_raw;
            end
            try
                app.OCTdata.range_y = data.OCT_range_y;
            end
            try
                app.OCTdata.range_z = data.OCT_range_z;
            end
            app.outputVideo();
            app.TimeTransform();
        end

        function Loadfile(app, fname, fpath, config)
            app.renderLamp.Color = [1, 0, 0];
            drawnow;
            app.Label_2.Text = '';
            [~, ~, ext] = fileparts(fname);
            app.extension = ext;

            app.filename = fname;
            app.filepath = fpath;
            switch ext
                case '.cine'
                    app.interferogram_stream = CineReader(fullfile(app.filepath, app.filename));

                    % fetch data from cine file
                    app.Fs = double(app.interferogram_stream.frame_rate);
                    % set f values from Fs
                    if app.Fs > 20000
                        app.lowfrequencyCheckBox.Value = false;
                        app.f1EditField.Value = 6;
                        app.f2EditField.Value = floor(app.Fs / (2*1000));

                        app.compositef1EditField.Value = 2;
                        app.compositef2EditField.Value = 10;
                        app.compositef3EditField.Value = floor(app.Fs / (2*1000));
                    elseif app.Fs > 2000
                        app.lowfrequencyCheckBox.Value = true;

                        app.f1EditField.Value = 0.2;
                        app.f2EditField.Value = floor(app.Fs / (2*1000));

                        app.compositef1EditField.Value = 0.2;
                        app.compositef2EditField.Value = 1;
                        app.compositef3EditField.Value = floor(app.Fs / (2*1000));
                    else
                        app.lowfrequencyCheckBox.Value = true;

                        app.f1EditField.Value = floor(app.Fs / (8*1000));
                        app.f2EditField.Value = floor(app.Fs / (2*1000));

                        app.compositef1EditField.Value = floor(app.Fs / (8*1000));
                        app.compositef2EditField.Value = floor(app.Fs / (4*1000));
                        app.compositef3EditField.Value = floor(app.Fs / (2*1000));
                    end

                    % update GUI text
                    % color_f1 = app.compositef1EditField.Value;
                    % color_f2 = app.compositef2EditField.Value;
                    % color_f3 = app.compositef3EditField.Value;

                    %                     if ~app.lowfrequencyCheckBox.Value
                    %                         app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                    %                         app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
                    %                     else
                    %                         app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                    %                         app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
                    %                     end

                    app.pix_width = 1 / double(app.interferogram_stream.horizontal_pix_per_meter);
                    app.pix_height = 1 / double(app.interferogram_stream.vertical_pix_per_meter);
                    app.Nx = double(app.interferogram_stream.frame_width);
                    app.Ny = double(app.interferogram_stream.frame_height);
                case '.raw'
                    % raw file - fetching missing data from user

                    % Dialog box
                    prompt = {'Nx:', 'Ny:', 'Fs:', 'pix width:', 'pix height:', 'endianness (b/l):'};
                    dlgtitle = 'Raw file detected - Input missing data';
                    dims = [1 35];
                    definput = {'512', '512', '67000', '28e-6', '28e-6', 'l'};

                    answer = inputdlg(prompt, dlgtitle, dims, definput);
                    frame_width = floor(str2double(answer{1}));
                    frame_height = floor(str2double(answer{2}));
                    app.Fs = floor(str2double(answer{3}));
                    app.pix_width = str2double(answer{4});
                    app.pix_height = str2double(answer{5});
                    endianness = answer{6};

                    if isempty(frame_width) || isempty(frame_height) || isempty(app.Fs) || isempty(app.pix_width) || isempty(app.pix_height) || isempty(endianness)
                        error('Bad raw data input - file could not be loaded correctly');
                    end

                    app.f2EditField.Value = floor(app.Fs / (2*1000));
                    app.compositef3EditField.Value = floor(app.Fs / (2*1000));

                    acquisition = DopplerAcquisition(frame_width, frame_height,app.Fs/1000,app.z_reconstruction, app.z_retina, app.z_iris ,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
                    app.interferogram_stream = RawReader(fullfile(app.filepath, app.filename), endianness, acquisition, app.batchsizeEditField.Value, app.batchstrideEditField.Value);

                    app.Nx = app.interferogram_stream.acquisition.Nx;
                    app.Ny = app.interferogram_stream.acquisition.Ny;
                case '.holo'
                    app.interferogram_stream = HoloReader(fullfile(app.filepath, app.filename));

                    % Older version : DialogWindow, now direct reconstruction
                    % with previous settings
                    %app.DialogApp = dialogWindow(app);


                    app.wavelengthEditField.Value = app.interferogram_stream.footer.compute_settings.image_rendering.lambda;

                    app.Nx = single(app.interferogram_stream.frame_height);
                    app.Ny = single(app.interferogram_stream.frame_width); %% find the real info in the footer
                    app.pix_width = app.interferogram_stream.footer.info.pixel_pitch.x * 1e-6;
                    app.pix_height = app.interferogram_stream.footer.info.pixel_pitch.y * 1e-6;

                    app.zretinaEditField.Value = app.interferogram_stream.footer.compute_settings.image_rendering.propagation_distance;

                    % FIXME
                    % for now set default Fs
                    app.Fs = 1500;

                    app.f1EditField.Value = 0.1;
                    app.f2EditField.Value = app.Fs / (2*1000);

                    app.compositef1EditField.Value = 0.2;
                    app.compositef2EditField.Value = 1;
                    app.compositef3EditField.Value = floor(app.Fs / (2*1000));

                    app.lowfrequencyCheckBox.Value = false;
                    if isfield(app.interferogram_stream.footer.compute_settings, 'batchSize')
                        reconstructWithPreviousSettings(app, 1)
                    end
            end

            % failsafe: set max batch size to file size
            % FIXME: check integrity of num_frames with footer
            % app.interferogram_stream.num_frames is read from header by
            % the function HoloReader

            if double(app.interferogram_stream.num_frames) < app.batchsizeEditField.Value
                app.batchsizeEditField.Value = round(double(app.interferogram_stream.num_frames) / 2);
                app.refbatchsizeEditField.Value = app.batchsizeEditField.Value;
            end

            % display file name in GUI
            app.CurrentFilePanel.Title = sprintf("Current file: %s", fullfile(app.filepath, app.filename));
            app.fsEditField.Value = app.Fs/1000;

            % change position slider limits according to number of frames in the file
            app.positioninfileSlider.Limits = [0, max(double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value), 1)];
            app.positioninfileSlider.Value = 0;
            app.EditField.Limits = [0, max(double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value), 1)];
            app.EditField.Value = 0;
            app.num_batches.Text = sprintf("/ %d", app.interferogram_stream.num_frames);


            %% Load settings from previous runs if available
            %  This might not work, for instance if previous runs were made
            %  with a different version of the software, such that the
            %  cache structure is different and cannot be loaded
            try
                [cache, cache_found] = fetch_cache(app.filepath, app.filename, ext);
            catch
                % the directory is present but is missing files or contains
                % corrupt data
                cache = [];
                cache_found = false;
            end

            try
                [image_registration, image_registration_found] = fetch_image_registration(app.filepath, app.filename, ext);
            catch
                % the directory is present but is missing files or contains
                % corrupt data
                image_registration = [];
                image_registration_found = false;
            end

            if image_registration_found
                app.image_registration = image_registration;
            end

            if ~isempty(config)
                copy_data_to_front(app, config);
            elseif cache_found
                copy_data_to_front(app, cache);
            else
                % set default gui parameters

                % do nothing, default parameters are already set in the GUI
                % toolbox
            end

            try
                [rephasing_data, rephasing_found] = fetch_rephasing_data(app.filepath, app.filename, ext);
            catch
                rephasing_data = [];
                rephasing_found = false;
            end

            if rephasing_found
                app.rephasing_data = rephasing_data;
                app.NotesTextArea.Value = {''};
                for i = 1:numel(rephasing_data)
                    r = rephasing_data(i);
                    reg = ~isempty(r.aberration_correction.rephasing_zernike_coefs);
                    shack = ~isempty(r.aberration_correction.shack_hartmann_zernike_coefs);
                    opt = ~isempty(r.aberration_correction.iterative_opt_zernike_coefs);
                    text = sprintf('Run %d:\nsize = %d, stride = %d, reg = %s, shack = %s, opt = %s', i, r.batch_size, r.batch_stride, string(reg), string(shack), string(opt));

                    % compute mean correction
                    if shack
                        mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:));
                        mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:));
                        mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:));
                    end

                    if opt
                        mean_1 = mean(r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                        mean_2 = mean(r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                        mean_3 = mean(r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                    end

                    if shack && opt
                        mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:) + r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                        mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:) + r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                        mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:) + r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                    end

                    if shack || opt
                        text = sprintf('%s\nastig 1 = %0.1f, defocus = %0.1f, astig 2 = %0.1f', text, mean_1, mean_2, mean_3);
                    end

                    text = sprintf('%s\n',text);

                    app.NotesTextArea.Value = [{text}; app.NotesTextArea.Value];
                end
                app.NotesTextArea.Value = [{'Rephasing found:'}; app.NotesTextArea.Value];
            else
                app.NotesTextArea.Value = {''};
            end

            % load data required to display images in the GUI
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));

            %% display images on file load

            app.z_iris = app.zirisEditField.Value;
            app.z_retina = app.zretinaEditField.Value;
            switch app.Switch.Value
                case 'z_iris'
                    app.z_reconstruction = app.z_iris;
                otherwise
                    app.z_reconstruction = app.z_retina;
            end

            % compute FH
            app.SubAp_PCA.Value = app.SubAp_PCACheckBox.Value;
            app.SubAp_PCA.min = app.minSubAp_PCAEditField_2.Value;
            app.SubAp_PCA.max = app.maxSubAp_PCAEditField_2.Value;

            app.time_transform.type = app.timetransformDropDown.Value;
            app.time_transform.f1 = app.f1EditField.Value;
            app.time_transform.f2 = app.f2EditField.Value;
            app.time_transform.min_PCA = app.min_PCAEditField.Value;
            app.time_transform.max_PCA = app.max_PCAEditField.Value;


            app.file_loaded = true;
            app.compute_kernel(false);


            RenderpreviewButtonPushed(app, 1);
            %             GPUpreview = app.check_GPU_for_preview();
            %             app.compute_FH(GPUpreview);
            %             app.compute_hologram(GPUpreview);
            %             app.show_hologram();
            % LoadfileButtonPushed ends
        end

        function parfor_arg = numCPUCores(app, safety_ratio) % FIXME safety_ratio 0.1 ?
            %             safety_ration = 0.1;
            FH = app.FH;
            s = whos('FH');
            [user, sys] = memory;
            a = sys.PhysicalMemory.Available; % total physical CPU memory
            user.MaxPossibleArrayBytes; % includes swap disk
            user.MemAvailableAllArrays; % includes swap disk
            nbytes_FH_SVD = s.bytes;
            if app.SVDCheckBox.Value
                nbytes_FH_SVD = s.bytes * 2; % reserved CPU memory in case of FH + SVD
            end
            fprintf('nBytes_FH_SVD: %d Bytes\n', nbytes_FH_SVD);
            fprintf('Physical memory available: %d Bytes\n', a);
            parfor_arg = max(floor(safety_ratio * a / nbytes_FH_SVD), 1);
            fprintf('Numbers of CPU cores: %d\n', parfor_arg);
        end

        function coefs = compute_ShackHartmann(app, peripheral_flag)
            if ~app.file_loaded
                return
            end

            app.compute_FH(false);

            zernike_ranks = app.shackhartmannzernikeranksEditField.Value;
            num_subapertures_positions = app.subapnumpositionsEditField.Value;
            image_subapertures_size_ratio = app.imagesubapsizeratioEditField.Value;
            subaperture_margin = app.subaperturemarginEditField.Value;
            ref_image = app.referenceimageDropDown.Value;
            %             zernike_projection = app.ZernikeProjectionCheckBox.Value;

            %num_subapertures_inter = 7;

            calibration_factor = 60;
            corrmap_margin = 0.4;

            power_filter_corrector = 1;
            sigma_filter_corrector = 1;

            % 3D PSF parameters in iterative Shack-Hartmann
            m = 128;
            defocus_range = 0.05;
            num_iter = 1;
            iter_phase = zeros(app.Ny, app.Nx, num_iter);
            psf_3d = zeros(app.Ny, app.Nx, m);


            [~, output_file_name, ~] = fileparts(app.filename);
            output_file_name = strcat(output_file_name, '_coefs');
            suffix = 0;

            while exist(fullfile(app.filepath, sprintf("%s_%d.txt", output_file_name, suffix)), 'file')
                suffix = suffix + 1;
                %                     disp(suffix);
            end
            output_file_name = sprintf("%s_%d", output_file_name, suffix);

            output_file_name = strcat(output_file_name, '.txt');
            output_file = fullfile(app.filepath, output_file_name);
            disp(output_file);

            % select subapertures to exclude, depending on the number
            % of subaperture used. This is chosen experimentally, the
            % value of the number of subapertures is constrained in the
            % GUI so the following switch should always branch to a
            % valid choice of excluded_subapertures
            %                 switch num_subapertures
            %                     case 3
            %                         excluded_subapertures = [];
            %                     case 4
            %                         excluded_subapertures = [1; 4; 13; 16];
            %                     case 5
            %                         excluded_subapertures = [1; 5; 21; 25];
            %                     case 6
            %                         excluded_subapertures = [1; 2; 5; 6; 7; 12; 25; 30; 31; 32; 35; 36];
            %
            %                     case 7
            %                         excluded_subapertures = [1; 2; 6; 7; 8; 14; 36; 42; 43; 44; 48; 49];
            %                     case 8
            %                         excluded_subapertures = [1; 2; 7; 8; 9; 16; 49; 56; 57; 58; 63; 64];
            %                     otherwise
            %                         error('Unreachable code was reached. Check value of num_subapertures');
            %                 end


            switch zernike_ranks
                case 2
                    zernike_indices = [3 4 5];
                case 3
                    zernike_indices = [3 4 5 6 7 8 9];
                case 4
                    zernike_indices = [3 4 5 6 7 8 9 10 11 12 13 14];
                case 5
                    zernike_indices = [3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
                case 6
                    zernike_indices = [3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27];
                otherwise
                    error('Unreachable code was reached. Check value of num_subapertures');
            end

            if peripheral_flag
                zernike_indices = 4;
            end

            shack_hartmann = ShackHartmann(image_subapertures_size_ratio, num_subapertures_positions, zernike_indices, calibration_factor,subaperture_margin,corrmap_margin,power_filter_corrector,sigma_filter_corrector, ref_image);
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000, app.z_reconstruction, app.z_retina, app.z_iris, app.wavelengthEditField.Value, app.DX, app.DY, app.pix_width, app.pix_height);


            %                 SubFH = shack_hartmann.SubField(app.FH);


            %             num_SubFH_x = floor((app.Nx - shack_hartmann.subimage_size) / shack_hartmann.subimage_stride) + 1;
            %             num_SubFH_y = floor((app.Ny - shack_hartmann.subimage_size) / shack_hartmann.subimage_stride) + 1;


            if app.setUpDropDown.Value == "Doppler"
                num_SubFH_z = 1;
            else % setup OCT
                num_SubFH_z = floor(size(app.FH, 3)/2/(length(app.OCTdata.range_z)-1));
            end

            %             shift_cell = cell(1, num_SubFH_z);
            coefs = cell(1,num_SubFH_z);
            idx_to_freq_coef = acquisition.fs/size(app.FH,3);
            layer_freq_thickness = floor(size(app.FH, 3)/2/num_SubFH_z) * idx_to_freq_coef;
            [M_aso, StitchedMomentsInMaso] = shack_hartmann.construct_M_aso(app.f1EditField.Value, app.f2EditField.Value,app.blur,acquisition);
            %             M_aso = M_aso / (512/shack_hartmann.n_SubAp); %why do we do this?
            M_aso = M_aso / (app.Nx/shack_hartmann.n_SubAp); %why do we do this?


            %                 M_aso = permute(M_aso, [2 1]);
            %                 M_aso = reshape(M_aso, 1, size(M_aso,1), size(M_aso, 2));
            %                 M_aso = svd_filter(M_aso, 1, 2);


            %                 y = round(384-shack_hartmann.subimage_size/2);
            FH = app.FH;
            begin = floor(now * 100000);
            for iter_idx = 1 : num_iter
                %                 H = ifft2(FH);
                % progress bar
                %                         current_index = (x-1)+(y-1)*num_SubFH_y+1;
                %                         last_index = num_SubFH_x*num_SubFH_y;
                %                         plop = (last_index - current_index) / current_index;
                %                         fprintf("%d / %d (%ds left)\n", current_index, last_index, floor(((now * 100000) - begin) * plop));
                %                         %
                %                         SubH = H(((y - 1) * shack_hartmann.subimage_stride) + 1:((y - 1) * shack_hartmann.subimage_stride) + shack_hartmann.subimage_size, ((x - 1) * shack_hartmann.subimage_stride) + 1:((x - 1) * shack_hartmann.subimage_stride) + shack_hartmann.subimage_size, :);
                %                         SubFH = fft2(SubH);
                SubFH = FH;

                for z = 1:num_SubFH_z
                    if app.setUpDropDown.Value == "OCT"
                        app.time_transform.f1 = (z-1)*layer_freq_thickness + idx_to_freq_coef;
                        app.time_transform.f2 = z * layer_freq_thickness;
                    end
                    [shifts, stiched_moments_subap, stiched_corr_subap] = shack_hartmann.compute_images_shifts(SubFH, app.time_transform.f1, app.time_transform.f2, app.blur, false, app.SVDCheckBox.Value, acquisition);
                    % Constriction of the matrix of xcorrelations of
                    % images in subapertures
                    %                             figure;
                    %                             imagesc(stiched_corr_subap);
                    %                         [~] = shack_hartmann.compute_temporal_SVD_in_SubAp(SubH, app.time_transform.f1, app.time_transform.f2, app.blur, false, app.SVDCheckBox.Value, acquisition);
                    shifts = shifts / (app.Nx/shack_hartmann.n_SubAp);
                    %                         central_shift = shifts(ceil(length(shifts)/2));
                    %                         shifts = shifts - central_shift;
                    %                             shift_cell{y, x, z} = shifts;
                    %                             disp(shifts);
                    %                             disp('-------------');
                    % remove corners
                    %                                                 excluded_subap = isnan(shifts);
                    excluded_subap = shack_hartmann.excluded_subapertures();
                    %                         disp(shifts);
                    shifts_original = shifts;
                    shifts(excluded_subap) = [];
                    M_aso_2 = M_aso;
                    M_aso_2(excluded_subap, :) = [];
                    %                         M_aso_2 = mat_mask(M_aso, excluded_subapertures);
                    %                         shifts = mat_mask(shifts, excluded_subapertures);
                    %
                    % separate x shifts from y shifts
                    Y = cat(1, real(shifts), imag(shifts));
                    %                         disp(M_aso_2);
                    M_aso_concat = cat(1,real(M_aso_2),imag(M_aso_2));
                    %

                    %                         % solve linear system
                    coef = M_aso_concat \ Y;

                    coefs{z} = coef * calibration_factor;
                end
                %                 disp(coefs{1, 1, 1});
                %FIXME
                % app.FH = SubFH;
                [FH, iter_phase(:,:,iter_idx)] = rephase_FH_for_preview(FH, coefs, zernike_indices);
            end

            for iter_idx = 1 : num_iter
                for i = -floor(m/2) + 1 : floor(m/2)
                    kernel = propagation_kernelAngularSpectrum(app.Ny, app.Nx, i*(defocus_range/m) , acquisition.lambda, app.pix_width, app.pix_height, 0);
                    psf_3d(:,:,i+floor(m/2)) = fftshift(ifft2(iter_phase(:,:,num_iter) .* kernel));
                end
                PSF2D_preview = (abs(squeeze(psf_3d(:,floor(app.Nx/2), :))));
                %figure;
                %imagesc(abs(squeeze(psf_3d(:,floor(app.Nx/2), :))));
                %figure;
                %volshow(abs(psf_3d));
            end


            %             A = zeros(size(coefs));

            %                 disp('-----------------');
            %             if app.savecoefsCheckBox.Value
            %                 [~, output_file_name, ~] = fileparts(app.filename);
            %                 output_file_name = strcat(output_file_name, '_coefs');
            %                 suffix = 0;
            %                 while exist(fullfile(app.filepath, sprintf("%s_%d.txt", output_file_name, suffix)), 'file')
            %                     suffix = suffix + 1;
            %                 end
            %                 output_file_name = sprintf("%s_%d", output_file_name, suffix);
            %                 output_file_name = strcat(output_file_name, '.txt');
            %                 output_file = fullfile(app.filepath, output_file_name);
            %                 z = 1;
            %                 writematrix([size(coefs) shack_hartmann.subimage_size shack_hartmann.subimage_stride], output_file, 'Delimiter', ',');
            %                 for pp = 1 : size(coefs{1,1,1}, 1)
            %                     for y = 1:size(coefs, 1)
            %                         for x = 1:size(coefs, 2)
            %                             % pp denotes coeficients, {x,y} the
            %                             % position of the sub image
            %                             A(x, y) = coefs{y, x, z}(pp);
            %                         end
            %                     end
            %                     writematrix(A, output_file, 'Delimiter', ',', 'WriteMode', 'append');
            %                 end
            %             end
            if ~peripheral_flag

                %                 imshow(mat2gray(stiched_moments_subap), 'Parent', app.UIAxes_3,...
                %                     'XData', [1 app.UIAxes_3.Position(3)], ...
                %                     'YData', [1 app.UIAxes_3.Position(4)]);
                image = stiched_moments_subap;
                if (size(image, 3) == 1)
                    image = repmat(image, 1, 1, 3);
                end
                app.ImageRight.ImageSource = image;

                centerY = ceil(size(coefs, 1) / 2);
                centerX = ceil(size(coefs, 2) / 2);
                centerZ = ceil(size(coefs, 3) / 2);
                app.PreviewLabel.Text = sprintf('astig_1 : %0.1f\ndefocus: %0.1f\nastig_2 : %0.1f', coefs{centerY, centerX, centerZ}(1), coefs{centerY, centerX, centerZ}(2), coefs{centerY, centerX, centerZ}(3));

                %Include the correction in the preview using calculated
                % zernike coefficients
                %% FIXME
                %                             shifts_original = M_aso(:,1);
                %             coefs{1} = [4, 0, 0];
                if app.ZernikeProjectionCheckBox.Value
                    [app.FH, app.phasePlane] = rephase_FH_for_preview(app.FH, coefs, zernike_indices);
                else
                    [~, app.phasePlane] = rephase_FH_for_preview(app.FH, coefs, zernike_indices);
                    % alternative rephasing
                    phase = stitch_phase(shifts_original, app.phasePlane, app.Nx, app.Ny, shack_hartmann);
                    app.FH = app.FH .* exp(-1i.* phase);
                end
                app.show_aberration_correction();
                image_before_correction = app.ImageLeft.ImageSource;
                %                 disp(["entropy of the image before correction :", entropy(image_before_correction)])
            end
        end
    end
    methods (Access = public)
        function compute_kernel(app, use_gpu)
            app.kernelAngularSpectrum = propagation_kernelAngularSpectrum(app.Nx, app.Ny, app.z_reconstruction, app.wavelengthEditField.Value, app.pix_width, app.pix_height, use_gpu);
            app.kernelFresnel = propagation_kernelFresnel(app.Nx, app.Ny, app.z_reconstruction, app.wavelengthEditField.Value, app.pix_width, app.pix_height, use_gpu);
        end

        function compute_FH(app, use_gpu) % FH for front-end preview
            %             use st = app.spatialTransformationDropDown.Value
            %             instead of :
            %             use st = app.cache.spatialTransformation
            %             because cache variables are not created yet with GuiCache(app)
            st = app.spatialTransformationDropDown.Value;
            if use_gpu
                switch st
                    case 'angular spectrum'
                        app.FH = gpuArray(fftshift(fft2(app.frame_batch)) .* app.kernelAngularSpectrum);
                    case 'Fresnel'
                        app.FH = gpuArray((app.frame_batch) .* app.kernelFresnel);
                end
            else % no gpu
                switch st
                    case 'angular spectrum'
                        app.FH = fftshift(fft2(app.frame_batch)) .* app.kernelAngularSpectrum;
                    case 'Fresnel'
                        app.FH = (app.frame_batch) .* app.kernelFresnel;
                end
            end

            if app.rephasingCheckBox.Value
                app.FH = rephase_FH(app.FH, app.rephasing_data, app.batchsizeEditField.Value, app.positioninfileSlider.Value);
            end
        end

        function reconstructWithPreviousSettings(app, YesOrNo)
            if YesOrNo == 1
                % app.interferogram_stream.footer = ;
                batchSize = app.interferogram_stream.footer.computeSettings.imageRendering.batchSize;

                app.zretinaEditField.Value = app.interferogram_stream.footer.computeSettings.imageRendering.zDistance;
                app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-batchSize)];
                app.positioninfileSlider.Value = min(app.positioninfileSlider.Value, app.positioninfileSlider.Limits(2));
                app.EditField.Limits = [0, max(double(app.interferogram_stream.num_frames-batchSize), 1)];
                app.EditField.Value = min(app.EditField.Value, app.EditField.Limits(2));
                app.frame_batch = app.interferogram_stream.read_frame_batch(batchSize, 0);
                app.compute_kernel(false);
                %% read the values from the footer and use them for reconstruction
            end
            %% set the reconstruction values
        end

        function compute_hologram(app, use_gpu)

            if ~app.file_loaded
                return;
            end

            % update cache parameters
            % save all gui parameters to a struct.
            % The current computation will fetch every parameter
            % from the cache and not from the gui.
            % The purpose of this is to prevent gui interactions
            % to mess up the parameters while a computation is ongoing.
            app.var_ImageTypeList.clear();
            app.cache = GuiCache(app);

            %FIXME : change function name DopplerAcquisition ->
            %HologramRenderingParameters & Remove Fs ?
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000, app.z_reconstruction, app.z_retina, app.z_iris, app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
            is_low_frequency = app.lowfrequencyCheckBox.Value;

            % change the reconstruction type name to a form compatible with
            % structures
            type = (strrep(app.ImageChoiceDropDown.Value, ' ', '_'));

            % set the select_boolean field value to true for the chosen
            % reconstruction
            switch app.time_transform.type
                case 'FFT'
                    type = (strrep(app.ImageChoiceDropDown.Value, ' ', '_'));
                case 'PCA'
                    type = 'pure_PCA';
            end

            app.var_ImageTypeList.(type).select();
            % reconstruct given image and store it in the struct


            if strcmp((type), 'dark_field_image')
                app.z_reconstruction = app.z_retina;
                compute_kernel(app, use_gpu);
                compute_FH(app, use_gpu);
                app.Switch.Value = 'z_retina';
                %ZSwitchValueChanged(app, []);
            end

            app.var_ImageTypeList.construct_image(app.FH, app.cache.wavelength, acquisition, app.blur, use_gpu, app.SVDCheckBox.Value,...
                app.SVDxCheckBox.Value, app.SVDx_SubApEditField.Value, [], app.compositef1EditField.Value, app.compositef2EditField.Value, app.compositef3EditField.Value, is_low_frequency, app.spatialTransformationDropDown.Value, app.time_transform, app.SubAp_PCA, app.xystrideEditField.Value, app.unitcellsinlatticeEditField.Value, app.r1EditField.Value, ...
                app.temporalCheckBox.Value, app.phi1EditField.Value, app.phi2EditField.Value, app.spatialCheckBox.Value, app.nu1EditField.Value, app.nu2EditField.Value);


            %% FIXME
            %             H_df = app.images.dark_field_image.H;
            %             [file_name, suffix] = get_last_file_name(app.filepath, 'H_df');
            %             output_dirname = sprintf('%s%s_%d.mat', app.filepath, file_name, suffix + 1);
            %             save(output_dirname, 'H_df');

            app.hologram = app.var_ImageTypeList.(type).image;
            app.hologram = flip(app.hologram);
            app.var_ImageTypeList.clear();


        end


        function show_hologram(app)
            if ~app.file_loaded
                return;
            end

            switch app.ImageChoiceDropDown.Value
                case 'power Doppler'
                    if app.lowfrequencyCheckBox.Value
                        sign = -1;
                    else
                        sign = 1;
                    end
                otherwise
                    sign = 1;
            end
            % display image preview
            %             imshow(mat2gray(sign*app.hologram), 'Parent', app.UIAxes_1,...
            %                 'XData', [1 app.UIAxes_1.Position(3)], ...
            %                 'YData', [1 app.UIAxes_1.Position(4)]);
            % FIXME trouver autre chose qu'un if
            image = mat2gray(sign * app.hologram);
            if (size(image, 3) == 1)
                image = repmat(image, 1, 1, 3);
            end
            app.ImageLeft.ImageSource = image;
            app.renderLamp.Color = [0, 1, 0];
        end

        function show_volume_projections (app)
            % image on the left
            tmp = mat2gray(squeeze(log(mean(app.OCTdata.stack(:,:,app.OCTdata.range_z),3))));
            app.ImageLeft.ImageSource = insertShape(tmp, 'Line',[1 app.OCTdata.range_y(1) 512 app.OCTdata.range_y(1)],'LineWidth',2,'Color','blue');
            app.ImageLeft.ImageSource = insertShape(app.ImageLeft.ImageSource, 'Line',[1 app.OCTdata.range_y(end) 512 app.OCTdata.range_y(end)],'LineWidth',2,'Color','blue');
            % image on the right

            tmp = mat2gray(squeeze(log(mean(app.OCTdata.stack(:,app.OCTdata.range_y,:),2))));
            rgb_tmp = zeros(size(tmp,1), size(tmp,2), 3);
            rgb_tmp(:,:, 1) = tmp *(0.6/max(tmp,[], "all"));
            rgb_tmp(:,:, 2) = tmp *(0.6/max(tmp,[], "all"));
            rgb_tmp(:,:, 3) = tmp *(0.6/max(tmp,[], "all"));
            %             for i = 1:size(rgb_tmp, 1)
            %                 if nnz(app.OCTdata.layer_1(i,app.OCTdata.range_y))~=0
            %                     layer_avg = squeeze(sum(app.OCTdata.layer_1(i,app.OCTdata.range_y))/nnz(app.OCTdata.layer_1(i,app.OCTdata.range_y)));
            %                     rgb_tmp(i, floor(layer_avg), 1) = 1;
            %                     rgb_tmp(i, floor(layer_avg), 2) = 0;
            %                     rgb_tmp(i, floor(layer_avg), 3) = 0;
            %                 end
            %                 if nnz(app.OCTdata.layer_2(i,app.OCTdata.range_y))~=0
            %                     layer_avg = squeeze(sum(app.OCTdata.layer_2(i,app.OCTdata.range_y))/nnz(app.OCTdata.layer_2(i,app.OCTdata.range_y)));
            %                     rgb_tmp(i, floor(layer_avg), 1) = 1;
            %                     rgb_tmp(i, floor(layer_avg), 2) = 0;
            %                     rgb_tmp(i, floor(layer_avg), 3) = 0;
            %                 end
            %             end
            rgb_tmp = permute(rgb_tmp, [2 1 3]);
            app.ImageRight.ImageSource = insertShape(rgb_tmp, 'Line',[1 app.OCTdata.range_z(1) 512 app.OCTdata.range_z(1)],'LineWidth',2,'Color','blue');
            app.ImageRight.ImageSource = insertShape(app.ImageRight.ImageSource, 'Line',[1 app.OCTdata.range_z(end) 512 app.OCTdata.range_z(end)],'LineWidth',2,'Color','blue');
            app.renderLamp.Color = [0, 1, 0];
        end

        function show_aberration_correction(app)
            if ~app.file_loaded
                return;
            end

            if app.ShackHartmannCheckBox.Value
                imshow(mat2gray(app.phasePlane), 'Parent', app.UIAxes_aberrationPreview,...
                    'XData', [1 app.UIAxes_aberrationPreview.Position(3)], ...
                    'YData', [1 app.UIAxes_aberrationPreview.Position(4)]);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set app constants
            app.blur = 35;
            app.file_loaded = false;
            app.DX = 0;
            app.DY = 0;

            % add subfolders to path
            addpath('constructImagesFunctions');
            addpath('post_processing');
            addpath('segmentationMaskFunctions');
            addpath('simulation_data');
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            %% open file and manage file extension
            if (isempty(app.filepath))
                [fname, fpath] = uigetfile('*.raw;*.cine;*.holo');
            else
                [fname, fpath] = uigetfile(strcat(app.filepath, "*.raw;*.cine;*.holo"));
            end
            if fname == 0
                return
            end
            config = fetch_config(fpath, fname);
            app.Loadfile(fname, fpath, config);
        end

        % Value changed function: zretinaEditField
        function zretinaEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.z_retina = app.zretinaEditField.Value;

            if app.Switch.Value == "z_retina"
                app.z_reconstruction = app.zretinaEditField.Value;
                app.compute_kernel(false);
            end
        end

        % Value changed function: f1EditField
        function f1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.time_transform.f1 = app.f1EditField.Value;
        end

        % Value changed function: f2EditField
        function f2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.time_transform.f2 = app.f2EditField.Value;
        end

        % Value changed function: wavelengthEditField
        function wavelengthEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.compute_kernel(false);
        end

        % Value changed function: batchsizeEditField
        function batchsizeEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.positioninfileSlider.Value = min(app.positioninfileSlider.Value, app.positioninfileSlider.Limits(2));
            app.EditField.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.EditField.Value = min(app.EditField.Value, app.EditField.Limits(2));
            app.max_PCAEditField.Limits = [0, double(app.batchsizeEditField.Value)];

            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, 0);
        end

        % Button pushed function: VideorenderingButton
        function VideorenderingButtonPushed(app, event)
            video_rendering(app);
        end

        % Button pushed function: AutofocusButton
        function AutofocusButtonPushed(app, event)
            if ~app.file_loaded
                return
            end

            f = waitbar(0, 'Autofocus in progress. Please wait...');
            nMax = 2; % autofocus iterations
            for nn = 1:nMax
                acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000, app.z_reconstruction, app.z_retina, app.z_iris, app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);

                % Run a Shack-Hartmann simulation on the current frame batch to
                % compute only defocus.

                FH = fftshift(fft2(app.frame_batch)) .* app.kernelAngularSpectrum;
                calibration_factor = 60;
                corrmap_margin = 0.4;
                subapertures4autofocus = 3;
                subaperturesinter4autofocus = 3;
                subapertureMargin4autofocus = 0.2;

                power_filter_corrector = 1;
                sigma_filter_corrector = 1;

                % select subapertures to exclude, depending on the number
                % of subaperture used. This is chosen experimentally, the
                % value of the number of subapertures is constrained in the
                % GUI so the following switch should always branch to a
                % valid choice of excluded_subapertures
                %             switch app.subaperturesEditField.Value
                %                 case 3
                %                     excluded_subapertures = [];
                %                 case 4
                %                     excluded_subapertures = [1; 4; 13; 16];
                %                 case 5
                %                     excluded_subapertures = [1; 5; 21; 25];
                %                 case 6
                %                     excluded_subapertures = [1; 2; 5; 6; 7; 12; 25; 30; 31; 32; 35; 36];
                %                 case 7
                %                     excluded_subapertures = [1; 2; 6; 7; 8; 14; 36; 42; 43; 44; 48; 49];
                %                 case 8
                %                     excluded_subapertures = [1; 2; 7; 8; 9; 16; 49; 56; 57; 58; 63; 64];
                %                 otherwise
                %                     error('Unreachable code was reached. Check value of num_subapertures');
                %             end
                shack_hartmann = ShackHartmann(subapertures4autofocus, subaperturesinter4autofocus, 4, calibration_factor, subapertureMargin4autofocus, corrmap_margin, power_filter_corrector, sigma_filter_corrector, 'central subaperture');
                f1 = app.f1EditField.Value;
                f2 = app.f2EditField.Value;
                M_aso = shack_hartmann.construct_M_aso(f1,f2,app.blur,acquisition);
                shifts = shack_hartmann.compute_images_shifts(FH,f1,f2,app.blur,false,true,acquisition);
                excluded_subapertures = shack_hartmann.excluded_subapertures();

                % remove corners
                M_aso = mat_mask(M_aso, excluded_subapertures);
                shifts = mat_mask(shifts, excluded_subapertures);

                % separate x shifts from y shifts
                Y = cat(1, real(shifts), imag(shifts));
                M_aso_concat = cat(1,real(M_aso),imag(M_aso));

                % solve linear system
                coefs = M_aso_concat \ Y;

                coefs = coefs * calibration_factor;

                z = double(app.z_reconstruction + (0.0025*512/app.Nx) * coefs(1));
                disp(z);
                if app.Switch.Value == "z_retina"
                    app.zretinaEditField.Value = z;
                    app.zretinaEditFieldValueChanged();
                else
                    app.zirisEditField.Value = z;
                    app.zirisEditFieldValueChanged();
                end
                app.RenderpreviewButtonPushed();
                waitbar(nn/nMax, f, 'Autofocus in progress. Please wait...');
            end
            close(f);
        end

        % Close request function: HolowavesUIFigure
        function HolowavesUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: positioninfileSlider
        function positioninfileSliderValueChanged(app, event)
            value = app.positioninfileSlider.Value;
            app.EditField.Value = value;

            if ~app.file_loaded
                return
            end

            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
        end

        % Value changed function: DxEditField
        function DxEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            value = app.DxEditField.Value;

            x_shift = floor(app.Nx * value);
            app.DX = x_shift;
        end

        % Value changed function: DyEditField
        function DyEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            value = app.DyEditField.Value;

            y_shift = floor(app.Ny * value);
            app.DY = y_shift;
        end

        % Value changed function: compositef1EditField
        function compositef1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            %
            %             color_f1 = app.compositef1EditField.Value;
            %             color_f2 = app.compositef2EditField.Value;
            %             color_f3 = app.compositef3EditField.Value;

            %             if ~app.lowfrequencyCheckBox.Value
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            %             else
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            %             end
        end

        % Value changed function: compositef2EditField
        function compositef2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            %
            %             color_f1 = app.compositef1EditField.Value;
            %             color_f2 = app.compositef2EditField.Value;
            %             color_f3 = app.compositef3EditField.Value;

            %             if ~app.lowfrequencyCheckBox.Value
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            %             else
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            %             end
        end

        % Value changed function: compositef3EditField
        function compositef3EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;

            %             if ~app.lowfrequencyCheckBox.Value
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            %             else
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            %             end
        end

        % Value changed function: lowfrequencyCheckBox
        function lowfrequencyCheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;

            %             if ~app.lowfrequencyCheckBox.Value
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            %             else
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            %             end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.positioninfileSlider.Value = value;app.rephasing_data

            if ~app.file_loaded
                return
            end

            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
        end

        % Value changed function: rephasingCheckBox
        function rephasingCheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            if app.rephasingCheckBox.Value
                try
                    [rephasing_data, rephasing_found] = fetch_rephasing_data(app.filepath, app.filename, app.extension);
                catch
                    rephasing_data = [];
                    rephasing_found = false;
                end

                if rephasing_found
                    app.rephasing_data = rephasing_data;

                    app.NotesTextArea.Value = {''};
                    for i = 1:numel(rephasing_data)
                        r = rephasing_data(i);
                        reg = ~isempty(r.aberration_correction.rephasing_zernike_coefs);
                        shack = ~isempty(r.aberration_correction.shack_hartmann_zernike_coefs);
                        opt = ~isempty(r.aberration_correction.iterative_opt_zernike_coefs);
                        text = sprintf('Run %d:\nsize = %d, stride = %d, reg = %s, shack = %s, opt = %s', i, r.batch_size, r.batch_stride, string(reg), string(shack), string(opt));

                        % compute mean correction
                        if shack
                            mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:));
                            mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:));
                            mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:));
                        end

                        if opt
                            mean_1 = mean(r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                            mean_2 = mean(r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                            mean_3 = mean(r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                        end

                        if shack && opt
                            mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:) + r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                            mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:) + r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                            mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:) + r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                        end

                        if shack || opt
                            text = sprintf('%s\nastig 1 = %0.1f, defocus = %0.1f, astig 2 = %0.1f', text, mean_1, mean_2, mean_3);
                        end

                        text = sprintf('%s\n',text);

                        app.NotesTextArea.Value = [{text}; app.NotesTextArea.Value];
                    end
                    app.NotesTextArea.Value = [{'Rephasing found:'}; app.NotesTextArea.Value];
                else
                    app.rephasing_data = [];
                    app.NotesTextArea.Value = {''};
                end
            end
        end

        % Value changed function: ImageChoiceDropDown
        function ImageChoiceDropDownValueChanged(app, event)
            value = app.ImageChoiceDropDown.Value;

            switch value
                case 'power Doppler'
                    app.f1EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f2EditFieldLabel.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef1EditFieldLabel.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef2EditFieldLabel.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'color Doppler'
                    app.f1EditField.Visible = false;
                    app.f1EditFieldLabel.Visible = false;
                    app.f2EditField.Visible = false;
                    app.f2EditFieldLabel.Visible = false;

                    app.compositef1EditField.Visible = true;
                    app.compositef1EditFieldLabel.Visible = true;
                    app.compositef2EditField.Visible = true;
                    app.compositef2EditFieldLabel.Visible = true;
                    app.compositef3EditField.Visible = true;
                    app.compositef3EditFieldLabel.Visible = true;

                    %                     app.LowfrequencyrangeLabel.Visible = true;
                    %                     app.HighfrequencyrangeLabel.Visible = true;

                case 'directional Doppler'
                    app.f1EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f2EditFieldLabel.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef1EditFieldLabel.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef2EditFieldLabel.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'velocity estimate'
                    app.f1EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f2EditFieldLabel.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef1EditFieldLabel.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef2EditFieldLabel.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'phase variation'
                    app.f1EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f2EditFieldLabel.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef1EditFieldLabel.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef2EditFieldLabel.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'dark field image'
                    app.f1EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f2EditFieldLabel.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef1EditFieldLabel.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef2EditFieldLabel.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'spectrogram'
                    app.f1EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f2EditFieldLabel.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef1EditFieldLabel.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef2EditFieldLabel.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;
                otherwise
                    % unreachable
            end
        end

        % Value changed function: blurEditField
        function blurEditFieldValueChanged(app, event)
            value = app.blurEditField.Value;
            app.blur = value;

            if ~app.file_loaded
                return
            end
        end

        % Value changed function: spatialTransformationDropDown
        function spatialTransformationChanged(app, event)
            app.compute_kernel(false);
        end

        % Value changed function: setUpDropDown
        function setUpChanged(app, event)
            if strcmp(app.setUpDropDown.Value,'OCT')
                app.rangeYEditField.Visible = true;
                app.rangeYEditFieldLabel.Visible = true;
                app.rangeZEditField.Visible = true;
                app.rangeZEditFieldLabel.Visible = true;
                app.volumesizeEditField.Visible = true;
                app.volumesizeEditFieldLabel.Visible = true;

                app.outputvideoDropDown.Value = "OCT cuts";
                app.outputvideoDropDown.Enable = false;
            else
                app.rangeYEditField.Visible = false;
                app.rangeYEditFieldLabel.Visible = false;
                app.rangeZEditField.Visible = false;
                app.rangeZEditFieldLabel.Visible = false;
                app.volumesizeEditField.Visible = false;
                app.volumesizeEditFieldLabel.Visible = false;
                app.outputvideoDropDown.Enable = true;
            end
        end

        % Button pushed function: SaveconfigButton
        function SaveconfigButtonPushed(app, event)
            app.saveconfigLamp.Color = [1, 0, 0];
            drawnow;
            config = GuiCache(app);
            if app.ApplytoallfilesCheckBox.Value
                files = dir(fullfile(app.filepath, '*.holo'));
                files = cat(1, files, dir(fullfile(app.filepath, '*.cine')));
                files = cat(1, files, dir(fullfile(app.filepath, '*.raw')));
                for n = 1:length(files)
                    [~, file_name, ~] = fileparts(files(n).name);
                    file_name = sprintf("%s-config", file_name);
                    [file_name, suffix] = get_last_file_name(app.filepath, file_name, 'mat');
                    save(sprintf('%s%s_%d.mat', app.filepath, file_name, suffix + 1), "config");
                end
            else
                [~, file_name, ~] = fileparts(app.filename);
                file_name = sprintf("%s-config", file_name);
                [file_name, suffix] = get_last_file_name(app.filepath, file_name, 'mat');
                save(sprintf('%s%s_%d.mat', app.filepath, file_name, suffix + 1), "config");
            end
            app.saveconfigLamp.Color = [0, 1, 0];
        end

        % Callback function
        function PCACheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.PCA.Value = app.PCACheckBox.Value;
            app.PCA.min = app.min_PCAEditField.Value;
            app.PCA.max = app.max_PCAEditField.Value;
        end

        % Value changed function: SubAp_PCACheckBox
        function SubAp_PCACheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.SubAp_PCA.Value = app.SubAp_PCACheckBox.Value;
            app.SubAp_PCA.min = app.minSubAp_PCAEditField_2.Value;
            app.SubAp_PCA.max = app.maxSubAp_PCAEditField_2.Value;
        end

        % Value changed function: minSubAp_PCAEditField_2
        function minSubApPCAEditField_2ValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.SubAp_PCA.min = app.minSubAp_PCAEditField_2.Value;
        end

        % Value changed function: maxSubAp_PCAEditField_2
        function maxSubAp_PCAEditField_2ValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.SubAp_PCA.max = app.maxSubAp_PCAEditField_2.Value;
        end

        % Value changed function: min_PCAEditField
        function min_PCAEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.time_transform.min_PCA = app.min_PCAEditField.Value;
        end

        % Value changed function: max_PCAEditField
        function max_PCAEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.time_transform.max_PCA = app.max_PCAEditField.Value;
        end

        % Value changed function: timetransformDropDown
        function TimeTransform(app, event)
            value = app.timetransformDropDown.Value;

            switch value
                case 'FFT'
                    app.time_transform.type = 'FFT';
                    % for now this is range of interest
                    app.time_transform.f1 = app.f1EditField.Value;
                    app.time_transform.f2 = app.f2EditField.Value;

                    app.ImageChoiceDropDown.Visible = true;
                    app.f1EditField.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditFieldLabel.Visible = true;

                    app.min_PCAEditField.Visible = false;
                    app.max_PCAEditField.Visible = false;
                    app.minEditFieldLabel.Visible = false;
                    app.maxEditFieldLabel.Visible = false;

                    if (strcmp(app.ImageChoiceDropDown.Value, 'color Doppler'))
                        app.f1EditField.Visible = false;
                        app.f2EditField.Visible = false;
                        app.f1EditFieldLabel.Visible = false;
                        app.f2EditFieldLabel.Visible = false;
                        app.compositef1EditField.Visible = true;
                        app.compositef1EditFieldLabel.Visible = true;
                        app.compositef2EditField.Visible = true;
                        app.compositef2EditFieldLabel.Visible = true;
                        app.compositef3EditField.Visible = true;
                        app.compositef3EditFieldLabel.Visible = true;

                        %                         app.LowfrequencyrangeLabel.Visible = true;
                        %                         app.HighfrequencyrangeLabel.Visible = true;
                    end

                case 'PCA'
                    app.time_transform.type = 'PCA';
                    app.time_transform.min_PCA = app.min_PCAEditField.Value;
                    app.time_transform.max_PCA = app.max_PCAEditField.Value;

                    app.ImageChoiceDropDown.Visible = false;
                    app.f1EditField.Visible = false;
                    app.f2EditField.Visible = false;
                    app.f1EditFieldLabel.Visible = false;
                    app.f2EditFieldLabel.Visible = false;

                    app.min_PCAEditField.Visible = true;
                    app.max_PCAEditField.Visible = true;
                    app.minEditFieldLabel.Visible = true;
                    app.maxEditFieldLabel.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef1EditFieldLabel.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef2EditFieldLabel.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;
            end
        end

        % Value changed function: Switch
        function ZSwitchValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            if app.Switch.Value == "z_iris"
                app.z_reconstruction = app.zirisEditField.Value;
            else
                app.z_reconstruction = app.zretinaEditField.Value;
            end

            app.compute_kernel(false);
        end

        % Value changed function: zirisEditField
        function zirisEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            app.z_iris = app.zirisEditField.Value;
            if app.Switch.Value == "z_iris"
                app.z_reconstruction = app.zirisEditField.Value;
                app.compute_kernel(false);
            end
        end

        % Value changed function: outputvideoDropDown
        function outputVideo(app, event)
            value = app.outputvideoDropDown.Value;
            if strcmp(value,'dark field')
                app.Switch.Value = 'z_retina';
                ZSwitchValueChanged(app, event);
            end
            if strcmp(value,'all videos')
                app.saverawvideosCheckBox.Enable = 'on';
            else
                app.saverawvideosCheckBox.Value = false;
                app.saverawvideosCheckBox.Enable = 'off';
            end
        end

        % Button pushed function: RenderpreviewButton
        function RenderpreviewButtonPushed(app, event)
            if ~app.file_loaded
                return
            end
            app.renderLamp.Color = [1, 0, 0];
            drawnow;
            GPUpreview = app.check_GPU_for_preview();
            app.compute_FH(GPUpreview);
            if app.ShackHartmannCheckBox.Value
                app.compute_ShackHartmann(false)
            end
            if strcmp(app.setUpDropDown.Value, 'Doppler')
                app.compute_hologram(GPUpreview);
                app.show_hologram();
            else % setup: OCT
                acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000, app.z_reconstruction, app.z_retina, app.z_iris, app.wavelengthEditField.Value, app.DxEditField.Value , app.DyEditField.Value,app.pix_width,app.pix_height);
                [M0, complex_M0] = reconstruct_hologram_stack(app.FH, app.time_transform, acquisition, 35, false, 0, 0, 0, [], app.volumesizeEditField.Value);
                %                 new_3D = load('video_3D.mat', '-mat');
                %                 app.OCTdata.stack = new_3D.video_3D * 100;
                app.OCTdata.stack = (M0);
                %                 [~, layer_idx] = identify_layers_in_OCT(complex_M0);
                %                 app.OCTdata.layer_1 = layer_idx(:,:, 1);
                %                 app.OCTdata.layer_2 = layer_idx(:,:, 2);
                %                 M0 = log(M0);
                %                 save(fullfile(app.filepath,'\volume.mat'), 'M0', '-mat');
                %                 app.OCTdata.stack = flatten_layers(app.OCTdata);
                app.OCTdata.range_y = min(app.OCTdata.range_y(1), size(app.OCTdata.stack, 2)):min(app.OCTdata.range_y(end), size(app.OCTdata.stack, 2));
                app.OCTdata.range_z = min(app.OCTdata.range_z(1), size(app.OCTdata.stack, 3)):min(app.OCTdata.range_z(end), size(app.OCTdata.stack, 3));
                app.show_volume_projections();
            end
        end

        % Window key press function: HolowavesUIFigure
        function HolowavesUIFigureWindowKeyPress(app, event)
            switch event.Key
                case 'return'
                    app.RenderpreviewButtonPushed();
            end
        end

        % Button pushed function: FoldermanagementButton
        function FoldermanagementButtonPushed(app, event)
            d = dialog('Position', [300, 300, 750, 90 + length(app.drawer_list) * 14],...
                'Color', [0.2, 0.2, 0.2],...
                'Name', 'Folder management',...
                'Resize', 'on',...
                'WindowStyle', 'normal');

            txt = uicontrol('Parent', d,...
                'Style', 'text',...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.2, 0.2, 0.2],...
                'ForegroundColor', [0.8, 0.8, 0.8],...
                'Position', [20, 70, 710, length(app.drawer_list) * 14],...
                'HorizontalAlignment', 'left',...
                'String', app.drawer_list);

            uicontrol('Parent', d,...
                'Position', [20, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Select folder',...
                'Callback', @select);

            uicontrol('Parent', d,...
                'Position', [140, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Clear list',...
                'Callback', @clear_drawer);

            uicontrol('Parent', d,...
                'Position', [260, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Render',...
                'Callback', @render);

            uiwait(d);

            function select(~, ~)
                selected_dir = uigetdir();
                if (selected_dir)
                    app.drawer_list{end + 1} = strcat(selected_dir, '\');
                end
                txt.String = app.drawer_list;
                d.Position(4) = 100 + length(app.drawer_list) * 14;
                txt.Position(4) = length(app.drawer_list) * 14;
            end

            function clear_drawer(~, ~)
                app.drawer_list = {};
                txt.String = app.drawer_list;
                d.Position(4) = 100 + length(app.drawer_list) * 14;
                txt.Position(4) = length(app.drawer_list) * 14;
            end

            function render(~, ~)
                file_list = {};
                for it_1 = 1:length(app.drawer_list) % Circles through the folders added by the user
                    FolderInfo = dir(app.drawer_list{it_1});
                    for it_2 = 1:length(FolderInfo) % Circles through the files of said folder
                        if (regexp(FolderInfo(it_2).name, "^[A-Za-z0-9_]+\.(holo|cine|raw)$")) % If the file is processable
                            config_list = get_all_config_file(app.drawer_list{it_1}, FolderInfo(it_2).name); % Get config files
                            if (~isempty(config_list))
                                file_list{end + 1} = {app.drawer_list{it_1}, FolderInfo(it_2).name, config_list}; %#ok<AGROW>
                            end
                        end
                    end
                end

                for i = 1:length(file_list)
                    if (~isempty(file_list{i}{3}))
                        for j = 1:length(file_list{i}{3})
                            config = load(fullfile(file_list{i}{1}, file_list{i}{3}{j}));
                            app.Loadfile(file_list{i}{2}, file_list{i}{1}, config.config);
                            app.VideorenderingButtonPushed();
                        end
                    end
                end
            end
            delete(d);
        end

        % Image clicked function: ImageRight
        function ImageRightClicked(app, event)
            if (strcmp(app.setUpDropDown.Value, "OCT"))
                % read data from "click"
                cursorPos = app.HolowavesUIFigure.CurrentPoint - app.ImageRight.Position(1:2);
                linePos = floor(size(app.OCTdata.stack, 3) - (cursorPos(2) / app.ImageRight.Position(4) * size(app.OCTdata.stack, 3)));
                app.OCTdata.range_z = min(linePos, size(app.OCTdata.stack, 3)) : min(linePos + app.rangeZEditField.Value, size(app.OCTdata.stack, 3));
                app.show_volume_projections();
            end
        end

        % Image clicked function: ImageLeft
        function ImageLeftClicked(app, event)
            if (strcmp(app.setUpDropDown.Value, "OCT"))
                % read data from "click"
                cursorPos = app.HolowavesUIFigure.CurrentPoint - app.ImageLeft.Position(1:2);
                linePos = floor(size(app.OCTdata.stack, 1) - (cursorPos(2) / app.ImageLeft.Position(4) * size(app.OCTdata.stack, 1)));
                app.OCTdata.range_y = min(linePos, size(app.OCTdata.stack, 2)) : min(linePos + app.rangeYEditField.Value, size(app.OCTdata.stack, 2));
                app.show_volume_projections();
            end
        end

        % Value changed function: rangeYEditField
        function rangeYEditFieldValueChanged(app, event)
            app.OCTdata.range_y = app.OCTdata.range_y(1) : min(app.OCTdata.range_y(1) + app.rangeYEditField.Value, size(app.OCTdata.stack, 2));
            app.show_volume_projections();
        end

        % Value changed function: rangeZEditField
        function rangeZEditFieldValueChanged(app, event)
            app.OCTdata.range_z = app.OCTdata.range_z(1) : min(app.OCTdata.range_z(1) + app.rangeZEditField.Value, size(app.OCTdata.stack, 3));
            app.show_volume_projections();
        end

        % Button pushed function: SavepreviewButton
        function SavePreview(app, event)

            %FIXME only works for FFT and darkfiel doesn't work "out of gpu
            %memory"

            app.savelamp.Color = [1, 0, 0];
            app.cache = GuiCache(app);

            % ,''dark_field_image',
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000, app.z_reconstruction, app.z_retina, app.z_iris, app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
            is_low_frequency = app.lowfrequencyCheckBox.Value;

            GPUpreview = app.check_GPU_for_preview();
            app.compute_FH(GPUpreview);

            [~,file_name,~] = fileparts(app.filename);
            folder_name = strcat( file_name, '_preview');
            if exist(fullfile(app.filepath, folder_name), 'dir')
                output_dirpath = fullfile(app.filepath, folder_name);
            else
                mkdir(fullfile(app.filepath,folder_name));
                output_dirpath = fullfile(app.filepath, folder_name);
            end
            preview_folder_name = create_output_directory_name(output_dirpath, sprintf('%s_%s.%s', file_name,'preview', 'cine'));
            mkdir(fullfile(output_dirpath,preview_folder_name));
            output_dirpath = fullfile(output_dirpath, preview_folder_name);

            %Compute and save all the doppler images save them


            mkdir(fullfile(output_dirpath, 'png'));
            folder_path_png = fullfile(output_dirpath, 'png');
            mkdir(fullfile(output_dirpath, 'freq_shift'));
            folder_path_freq = fullfile(output_dirpath, 'freq_shift');

            %% PNG

            app.var_ImageTypeList.clear();
            app.var_ImageTypeList.select('power_Doppler','color_Doppler','directional_Doppler','velocity_estimate', 'phase_variation',  'spectrogram');
            app.var_ImageTypeList.construct_image(app.FH, app.cache.wavelength, acquisition, app.blur, false, app.SVDCheckBox.Value,...
                app.SVDxCheckBox.Value, app.SVDx_SubApEditField.Value, [], app.compositef1EditField.Value, app.compositef2EditField.Value, app.compositef3EditField.Value, is_low_frequency, app.spatialTransformationDropDown.Value, app.time_transform, app.SubAp_PCA, app.xystrideEditField.Value, app.unitcellsinlatticeEditField.Value, app.r1EditField.Value, ...
                app.temporalCheckBox.Value, app.phi1EditField.Value, app.phi2EditField.Value, app.spatialCheckBox.Value, app.nu1EditField.Value, app.nu2EditField.Value);
            app.var_ImageTypeList.images_png(preview_folder_name,folder_path_png,'power_Doppler','color_Doppler','directional_Doppler','velocity_estimate', 'phase_variation',  'spectrogram')

            %% Frequency Shift
            freq_nyq = app.Fs/1000;
            local_num_frame = 5;

            freq_basse = linspace(0.2,freq_nyq/8,local_num_frame);
            freq_haute = linspace(1,freq_nyq/2,local_num_frame);

            %video = zeros(app.Nx, app.Ny, 1, 5*local_num_frame,'single');
            app.var_ImageTypeList.clear();
            app.var_ImageTypeList.select('power_Doppler');

            for i=1:local_num_frame

                app.time_transform.f1 = freq_basse(i);
                app.time_transform.f2 = freq_haute(i);
                app.var_ImageTypeList.construct_image(app.FH, app.cache.wavelength, acquisition, app.blur, false, app.SVDCheckBox.Value,...
                    app.SVDxCheckBox.Value, app.SVDx_SubApEditField.Value, [], app.time_transform.f1, app.time_transform.f2, app.compositef3EditField.Value, is_low_frequency, app.spatialTransformationDropDown.Value, app.time_transform, app.SubAp_PCA, app.xystrideEditField.Value, app.unitcellsinlatticeEditField.Value, app.r1EditField.Value, ...
                    app.temporalCheckBox.Value, app.phi1EditField.Value, app.phi2EditField.Value, app.spatialCheckBox.Value, app.nu1EditField.Value, app.nu2EditField.Value);

                app.hologram = app.var_ImageTypeList.('power_Doppler').image;
                app.hologram = flip(app.hologram);
                app.hologram = mat2gray(app.hologram);
                image_temp_2 = app.hologram;

                preview_name_freq = sprintf('%s_%s_%.1f_%.1f.%s', preview_folder_name, 'freq_shift',round(app.time_transform.f1,1) ,round(app.time_transform.f2,1) , 'png');
                imwrite(image_temp_2, fullfile(folder_path_freq, preview_name_freq));

                %Generate a video of the freq shift
                %video(:,:,:,indice) = image_temp_2;
                %video(:,:,:,indice+1) = image_temp_2;
                %video(:,:,:,indice+2) = image_temp_2;
                %video(:,:,:,indice+3) = image_temp_2;
                %video(:,:,:,indice+4) = image_temp_2;
                %indice = indice+5;


            end

            %preview_name_temp = sprintf('%s_%s.%s', preview_folder_name, 'video_freq' , 'png');
            %generate_video(video, output_dirpath, 'video_freq', 0.0005, app.cache.temporal_filter, is_low_frequency, 0, true);

            app.var_ImageTypeList.clear();

            %Correctly name and write the precomputated images
            preview_name = sprintf('%s.%s', preview_folder_name, 'png');
            image_left = app.ImageLeft.ImageSource;
            alpha = 10;

            if app.ShackHartmannCheckBox.Value
                image_right = app.ImageRight.ImageSource;


                image_phase = mat2gray(app.phasePlane);
                image_psf2D = mat2gray((ones(app.Nx)-abs(fftshift(ifft2(exp(1i*alpha.*app.phasePlane))))));


            end

            if app.ShackHartmannCheckBox.Value
                preview_name_sh = sprintf('%s_%s.%s', preview_folder_name, 'SH', 'png');
                preview_name_phase = sprintf('%s_%s.%s', preview_folder_name, 'phase', 'png');
                preview_name_psf2D = sprintf('%s_%s.%s', preview_folder_name, 'psf2D', 'png');

                imwrite(image_right, fullfile(folder_path_png, preview_name_sh));
                imwrite(image_phase, fullfile(folder_path_png, preview_name_phase));
                imwrite(image_psf2D, fullfile(folder_path_png, preview_name_psf2D));
            end


            imwrite(image_left, fullfile(folder_path_png, preview_name));

            app.savelamp.Color = [0, 1, 0];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HolowavesUIFigure = uifigure('Visible', 'off');
            app.HolowavesUIFigure.Name = 'Holowaves';
            app.HolowavesUIFigure.CloseRequestFcn = createCallbackFcn(app, @HolowavesUIFigureCloseRequest, true);
            app.HolowavesUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @HolowavesUIFigureWindowKeyPress, true);
            app.TabGroup = uitabgroup(app.HolowavesUIFigure);
            app.MainviewTab = uitab(app.TabGroup);
            app.MainviewTab.Title = 'Main view';
            app.FileselectionPanel = uipanel(app.MainviewTab);
            app.FileselectionPanel.Tooltip = {'Select a reconstruction algorithm that minimizes memory footprint during post processing'};
            app.FileselectionPanel.Title = 'File selection';
            app.VideorenderingPanel = uipanel(app.FileselectionPanel);
            app.VideorenderingPanel.Title = 'Video rendering';
            app.saverawvideosCheckBox = uicheckbox(app.VideorenderingPanel);
            app.saverawvideosCheckBox.Text = 'save raw videos';
            app.rephasingCheckBox = uicheckbox(app.VideorenderingPanel);
            app.rephasingCheckBox.ValueChangedFcn = createCallbackFcn(app, @rephasingCheckBoxValueChanged, true);
            app.rephasingCheckBox.Text = 'rephasing';
            app.rephasingCheckBox.Value = true;
            app.lowmemoryCheckBox = uicheckbox(app.VideorenderingPanel);
            app.lowmemoryCheckBox.Text = 'low memory';
            app.outputvideoDropDownLabel = uilabel(app.VideorenderingPanel);
            app.outputvideoDropDownLabel.Text = 'output video';
            app.outputvideoDropDown = uidropdown(app.VideorenderingPanel);
            app.outputvideoDropDown.Items = {'power Doppler', 'all videos', 'dark field', 'OCT cuts'};
            app.outputvideoDropDown.ValueChangedFcn = createCallbackFcn(app, @outputVideo, true);
            app.outputvideoDropDown.Value = 'power Doppler';
            app.phaseregistrationCheckBox = uicheckbox(app.VideorenderingPanel);
            app.phaseregistrationCheckBox.Visible = 'off';
            app.phaseregistrationCheckBox.Tooltip = {'Activates video registration. Check this if the video is too shaky.'};
            app.phaseregistrationCheckBox.Text = 'phase registration';
            app.temporalfilterCheckBox = uicheckbox(app.VideorenderingPanel);
            app.temporalfilterCheckBox.Text = 'temporal filter';
            app.temporalfilterEditField = uieditfield(app.VideorenderingPanel, 'numeric');
            app.temporalfilterEditField.Limits = [1 Inf];
            app.temporalfilterEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.temporalfilterEditField.Value = 2;
            app.FoldermanagementButton = uibutton(app.VideorenderingPanel, 'push');
            app.FoldermanagementButton.ButtonPushedFcn = createCallbackFcn(app, @FoldermanagementButtonPushed, true);
            app.FoldermanagementButton.Text = 'Folder management';
            app.VideorenderingButton = uibutton(app.VideorenderingPanel, 'push');
            app.VideorenderingButton.ButtonPushedFcn = createCallbackFcn(app, @VideorenderingButtonPushed, true);
            app.VideorenderingButton.Text = 'Video rendering';
            app.ApplytoallfilesCheckBox = uicheckbox(app.VideorenderingPanel);
            app.ApplytoallfilesCheckBox.Text = 'Apply to all files';
            app.saveconfigLamp = uilamp(app.VideorenderingPanel);
            app.iterativeregistrationCheckBox = uicheckbox(app.VideorenderingPanel);
            app.iterativeregistrationCheckBox.Tooltip = {'''Activates video registration. Check this if the video is too shaky.'''};
            app.iterativeregistrationCheckBox.Text = 'iterative registration';
            app.LoadfileButton = uibutton(app.FileselectionPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.batchstrideEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchstrideEditFieldLabel.Text = 'batch stride';
            app.batchstrideEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchstrideEditField.Limits = [1 Inf];
            app.batchstrideEditField.ValueDisplayFormat = '%.0f';
            app.batchstrideEditField.Tooltip = {'Number of interferograms to skip between two images. Increase for a shorter video.'};
            app.batchstrideEditField.Value = 1024;
            app.wavelengthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.wavelengthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.wavelengthEditField.Limits = [0 Inf];
            app.wavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @wavelengthEditFieldValueChanged, true);
            app.wavelengthEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.wavelengthEditField.Value = 8.52e-07;
            app.ParallelismDropDown = uidropdown(app.FileselectionPanel);
            app.ParallelismDropDown.Items = {'CPU multithread', 'GPU parallelization', 'CPU singlethread', 'CPU/GPU rendering/optim', ''};
            app.ParallelismDropDown.Value = 'CPU multithread';
            app.DxEditFieldLabel = uilabel(app.FileselectionPanel);
            app.DxEditFieldLabel.Text = 'Dx';
            app.DxEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.DxEditField.Limits = [-1 1.01];
            app.DxEditField.ValueChangedFcn = createCallbackFcn(app, @DxEditFieldValueChanged, true);
            app.DxEditField.Tooltip = {''};
            app.DyEditFieldLabel = uilabel(app.FileselectionPanel);
            app.DyEditFieldLabel.Text = 'Dy';
            app.DyEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.DyEditField.Limits = [-1 1.01];
            app.DyEditField.ValueChangedFcn = createCallbackFcn(app, @DyEditFieldValueChanged, true);
            app.DyEditField.Tooltip = {''};
            app.refbatchsizeEditFieldLabel = uilabel(app.FileselectionPanel);
            app.refbatchsizeEditFieldLabel.Text = 'ref batch size';
            app.refbatchsizeEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.refbatchsizeEditField.Limits = [1 Inf];
            app.refbatchsizeEditField.Tooltip = {'Number of interferograms used to generate a single image. Increase value for better video quality.'};
            app.refbatchsizeEditField.Value = 512;
            app.fsEditFieldLabel = uilabel(app.FileselectionPanel);
            app.fsEditFieldLabel.Text = 'fs';
            app.fsEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.fsEditField.Limits = [0 Inf];
            app.fsEditField.Editable = 'off';
            app.fsEditField.Tooltip = {'Sampling frequency (kHz)'};
            app.fsEditField.Value = 75;
            app.setUpDropDown = uidropdown(app.FileselectionPanel);
            app.setUpDropDown.Items = {'Doppler', 'OCT'};
            app.setUpDropDown.ValueChangedFcn = createCallbackFcn(app, @setUpChanged, true);
            app.setUpDropDown.Value = 'Doppler';
            app.spatialtransformationLabel = uilabel(app.FileselectionPanel);
            app.spatialtransformationLabel.Text = 'spatial transformation';
            app.spatialTransformationDropDown = uidropdown(app.FileselectionPanel);
            app.spatialTransformationDropDown.Items = {'Fresnel', 'angular spectrum'};
            app.spatialTransformationDropDown.ValueChangedFcn = createCallbackFcn(app, @spatialTransformationChanged, true);
            app.spatialTransformationDropDown.Value = 'angular spectrum';
            app.SaveconfigButton = uibutton(app.FileselectionPanel, 'push');
            app.SaveconfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigButtonPushed, true);
            app.SaveconfigButton.Text = 'Save config';
            app.NotesTextAreaLabel = uilabel(app.FileselectionPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.FileselectionPanel);
            app.imageregistrationCheckBox = uicheckbox(app.FileselectionPanel);
            app.imageregistrationCheckBox.Text = 'image registration';
            app.imageregistrationCheckBox.Value = true;
            app.numworkersSpinnerLabel = uilabel(app.FileselectionPanel);
            app.numworkersSpinnerLabel.Text = 'num workers';
            app.numworkersSpinner = uispinner(app.FileselectionPanel);
            app.numworkersSpinner.Limits = [-1 32];
            app.numworkersSpinner.Value = 8;
            app.CurrentFilePanel = uipanel(app.MainviewTab);
            app.CurrentFilePanel.Tooltip = {''};
            app.CurrentFilePanel.Title = 'Current file';
            app.GridLayout = uigridlayout(app.CurrentFilePanel);
            app.AdvancedProcessingPanel = uipanel(app.GridLayout);
            app.AdvancedProcessingPanel.Title = 'Advanced Processing';
            app.SVDCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.SVDCheckBox.Tooltip = {'Enable SVD hologram filtering in hologram construction'};
            app.SVDCheckBox.Text = 'SVD';
            app.SVDCheckBox.Value = true;
            app.spatialCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.spatialCheckBox.Text = 'spatial';
            app.temporalCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.temporalCheckBox.Text = 'temporal';
            app.LocalfilteringLabel = uilabel(app.AdvancedProcessingPanel);
            app.LocalfilteringLabel.Text = 'Local filtering';
            app.phi1EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.phi1EditFieldLabel.Text = 'phi1';
            app.phi1EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.phi2EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.phi2EditFieldLabel.Text = 'phi2';
            app.phi2EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.nu1EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.nu1EditFieldLabel.Text = 'nu1';
            app.nu1EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.nu2EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.nu2EditFieldLabel.Text = 'nu2';
            app.nu2EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.unitcellsinlatticeEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.unitcellsinlatticeEditFieldLabel.Text = '# unit cells in lattice';
            app.unitcellsinlatticeEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.unitcellsinlatticeEditField.Limits = [0 Inf];
            app.unitcellsinlatticeEditField.Value = 8;
            app.r1EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.r1EditFieldLabel.Text = 'r1';
            app.r1EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.r1EditField.Limits = [0 Inf];
            app.r1EditField.Value = 3;
            app.xystrideEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.xystrideEditFieldLabel.Text = 'xy stride';
            app.xystrideEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.xystrideEditField.Value = 32;
            app.SVDx_SubApEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.SVDx_SubApEditFieldLabel.Text = 'SVDx_SubAp';
            app.SVDx_SubApEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.SVDx_SubApEditField.Limits = [0 20];
            app.SVDx_SubApEditField.Value = 3;
            app.SVDxCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.SVDxCheckBox.Text = 'SVDx';
            app.AberrationcompensationPanel = uipanel(app.GridLayout);
            app.AberrationcompensationPanel.Title = 'Aberration compensation';
            app.UIAxes_aberrationPreview = uiaxes(app.AberrationcompensationPanel);
            app.UIAxes_aberrationPreview.CLim = [0 1];
            app.UIAxes_aberrationPreview.XTick = [];
            app.UIAxes_aberrationPreview.YTick = [];
            app.Label_2 = uilabel(app.AberrationcompensationPanel);
            app.Label_2.Text = {''; ''};
            app.ShackHartmannCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.ShackHartmannCheckBox.Tooltip = {'Perform aberration compensation with Shack Hartmann simulation'};
            app.ShackHartmannCheckBox.Text = 'Shack Hartmann';
            app.zernikeranksEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.zernikeranksEditFieldLabel.Text = 'zernike ranks';
            app.shackhartmannzernikeranksEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.shackhartmannzernikeranksEditField.Limits = [2 6];
            app.shackhartmannzernikeranksEditField.RoundFractionalValues = 'on';
            app.shackhartmannzernikeranksEditField.Tooltip = {'Number of zernike ranks to use for aberration correction'};
            app.shackhartmannzernikeranksEditField.Value = 2;
            app.subaperturemarginEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.subaperturemarginEditFieldLabel.Text = 'subaperture margin';
            app.subaperturemarginEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.subaperturemarginEditField.Limits = [0 Inf];
            app.subaperturemarginEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subaperturemarginEditField.Value = 0.3;
            app.IterativeoptimizationCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.IterativeoptimizationCheckBox.Tooltip = {'Activate aberration compensation via image entropy optimization. This might significantly increase the computation time.'};
            app.IterativeoptimizationCheckBox.Text = 'Iterative optimization';
            app.zernikeranksEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.zernikeranksEditField_2Label.Visible = 'off';
            app.zernikeranksEditField_2Label.Text = 'zernike ranks';
            app.optimizationzernikeranksEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.optimizationzernikeranksEditField.Limits = [2 Inf];
            app.optimizationzernikeranksEditField.Visible = 'off';
            app.optimizationzernikeranksEditField.Tooltip = {'Number of zernike ranks that constitue the aberration projection polynomial space'};
            app.optimizationzernikeranksEditField.Value = 2;
            app.toleranceLabel = uilabel(app.AberrationcompensationPanel);
            app.toleranceLabel.Visible = 'off';
            app.toleranceLabel.Text = 'tolerance';
            app.zernikestolEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.zernikestolEditField.Limits = [0 Inf];
            app.zernikestolEditField.Visible = 'off';
            app.zernikestolEditField.Tooltip = {''};
            app.zernikestolEditField.Value = 0.05;
            app.maxconstraintLabel = uilabel(app.AberrationcompensationPanel);
            app.maxconstraintLabel.Visible = 'off';
            app.maxconstraintLabel.Text = 'max constraint';
            app.maxconstraintEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.maxconstraintEditField.Limits = [1 Inf];
            app.maxconstraintEditField.RoundFractionalValues = 'on';
            app.maxconstraintEditField.Visible = 'off';
            app.maxconstraintEditField.Tooltip = {''};
            app.maxconstraintEditField.Value = 10;
            app.masknumiterLabel = uilabel(app.AberrationcompensationPanel);
            app.masknumiterLabel.Visible = 'off';
            app.masknumiterLabel.Text = 'mask num iter';
            app.masknumiterEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.masknumiterEditField.Limits = [1 Inf];
            app.masknumiterEditField.RoundFractionalValues = 'on';
            app.masknumiterEditField.Visible = 'off';
            app.masknumiterEditField.Tooltip = {''};
            app.masknumiterEditField.Value = 1;
            app.PreviewLabel = uilabel(app.AberrationcompensationPanel);
            app.PreviewLabel.Text = {'astig_1 : 0.0'; 'defocus: 0.0'; 'astig_2 : 0.0'};
            app.SubAp_PCACheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.SubAp_PCACheckBox.ValueChangedFcn = createCallbackFcn(app, @SubAp_PCACheckBoxValueChanged, true);
            app.SubAp_PCACheckBox.Text = 'SubAp_PCA';
            app.minEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.minEditField_2Label.Text = 'min';
            app.minSubAp_PCAEditField_2 = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.minSubAp_PCAEditField_2.Limits = [1 Inf];
            app.minSubAp_PCAEditField_2.ValueChangedFcn = createCallbackFcn(app, @minSubApPCAEditField_2ValueChanged, true);
            app.minSubAp_PCAEditField_2.Value = 1;
            app.maxEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.maxEditField_2Label.Text = 'max';
            app.maxSubAp_PCAEditField_2 = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.maxSubAp_PCAEditField_2.Limits = [1 Inf];
            app.maxSubAp_PCAEditField_2.ValueChangedFcn = createCallbackFcn(app, @maxSubAp_PCAEditField_2ValueChanged, true);
            app.maxSubAp_PCAEditField_2.Value = 16;
            app.volumesizeEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.volumesizeEditFieldLabel.Visible = 'off';
            app.volumesizeEditFieldLabel.Text = 'volume size';
            app.volumesizeEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.volumesizeEditField.Limits = [1 Inf];
            app.volumesizeEditField.Visible = 'off';
            app.volumesizeEditField.Value = 256;
            app.rangeZEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.rangeZEditFieldLabel.Visible = 'off';
            app.rangeZEditFieldLabel.Text = 'rangeZ';
            app.rangeZEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.rangeZEditField.Limits = [1 Inf];
            app.rangeZEditField.ValueChangedFcn = createCallbackFcn(app, @rangeZEditFieldValueChanged, true);
            app.rangeZEditField.Visible = 'off';
            app.rangeZEditField.Value = 1;
            app.rangeYEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.rangeYEditFieldLabel.Visible = 'off';
            app.rangeYEditFieldLabel.Text = 'rangeY';
            app.rangeYEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.rangeYEditField.Limits = [1 Inf];
            app.rangeYEditField.ValueChangedFcn = createCallbackFcn(app, @rangeYEditFieldValueChanged, true);
            app.rangeYEditField.Visible = 'off';
            app.rangeYEditField.Value = 1;
            app.savecoefsCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.savecoefsCheckBox.Text = 'save coefs';
            app.ZernikeProjectionCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.ZernikeProjectionCheckBox.Tooltip = {'Perform aberration compensation with Shack Hartmann simulation'};
            app.ZernikeProjectionCheckBox.Text = 'Zernike Projection';
            app.referenceimageDropDownLabel = uilabel(app.AberrationcompensationPanel);
            app.referenceimageDropDownLabel.Text = 'reference image';
            app.referenceimageDropDown = uidropdown(app.AberrationcompensationPanel);
            app.referenceimageDropDown.Items = {'central subaperture', 'resized image'};
            app.referenceimageDropDown.Value = 'central subaperture';
            app.subapnumpositionsEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.subapnumpositionsEditFieldLabel.Text = 'subap num positions';
            app.subapnumpositionsEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.subapnumpositionsEditField.Limits = [1 20];
            app.subapnumpositionsEditField.RoundFractionalValues = 'on';
            app.subapnumpositionsEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subapnumpositionsEditField.Value = 5;
            app.imagesubapsizeratioEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.imagesubapsizeratioEditFieldLabel.Text = 'image subap size ratio';
            app.imagesubapsizeratioEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.imagesubapsizeratioEditField.Limits = [1 20];
            app.imagesubapsizeratioEditField.RoundFractionalValues = 'on';
            app.imagesubapsizeratioEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.imagesubapsizeratioEditField.Value = 5;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ImagerenderingPanel = uipanel(app.GridLayout3);
            app.ImagerenderingPanel.Title = 'Image rendering';
            app.f1EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.f1EditFieldLabel.Text = 'f1';
            app.f1EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.f1EditField.Limits = [0 Inf];
            app.f1EditField.ValueChangedFcn = createCallbackFcn(app, @f1EditFieldValueChanged, true);
            app.f1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.f1EditField.Value = 8;
            app.f2EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.f2EditField.Limits = [0 Inf];
            app.f2EditField.ValueChangedFcn = createCallbackFcn(app, @f2EditFieldValueChanged, true);
            app.f2EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.f2EditField.Value = 30;
            app.compositef1EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.compositef1EditFieldLabel.Visible = 'off';
            app.compositef1EditFieldLabel.Text = 'f1';
            app.compositef1EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.compositef1EditField.Limits = [0 Inf];
            app.compositef1EditField.ValueChangedFcn = createCallbackFcn(app, @compositef1EditFieldValueChanged, true);
            app.compositef1EditField.Visible = 'off';
            app.compositef1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.compositef1EditField.Value = 1;
            app.compositef2EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.compositef2EditFieldLabel.Visible = 'off';
            app.compositef2EditFieldLabel.Text = 'f2';
            app.compositef2EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.compositef2EditField.Limits = [0 Inf];
            app.compositef2EditField.ValueChangedFcn = createCallbackFcn(app, @compositef2EditFieldValueChanged, true);
            app.compositef2EditField.Visible = 'off';
            app.compositef2EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.compositef2EditField.Value = 6;
            app.compositef3EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.compositef3EditFieldLabel.Visible = 'off';
            app.compositef3EditFieldLabel.Text = 'f3';
            app.compositef3EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.compositef3EditField.Limits = [0 Inf];
            app.compositef3EditField.ValueChangedFcn = createCallbackFcn(app, @compositef3EditFieldValueChanged, true);
            app.compositef3EditField.Visible = 'off';
            app.compositef3EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.compositef3EditField.Value = 30;
            app.ImageChoiceDropDown = uidropdown(app.ImagerenderingPanel);
            app.ImageChoiceDropDown.Items = {'power Doppler', 'color Doppler', 'directional Doppler', 'velocity estimate', 'phase variation', 'dark field image', 'spectrogram'};
            app.ImageChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageChoiceDropDownValueChanged, true);
            app.ImageChoiceDropDown.Value = 'power Doppler';
            app.gwLabel = uilabel(app.ImagerenderingPanel);
            app.gwLabel.Text = 'blur';
            app.blurEditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.blurEditField.Limits = [0 Inf];
            app.blurEditField.ValueChangedFcn = createCallbackFcn(app, @blurEditFieldValueChanged, true);
            app.blurEditField.Tooltip = {'Magnitude of gaussian filter'};
            app.blurEditField.Value = 35;
            app.timetransformDropDownLabel = uilabel(app.ImagerenderingPanel);
            app.timetransformDropDownLabel.Text = 'time transform';
            app.timetransformDropDown = uidropdown(app.ImagerenderingPanel);
            app.timetransformDropDown.Items = {'FFT', 'PCA'};
            app.timetransformDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeTransform, true);
            app.timetransformDropDown.Value = 'FFT';
            app.minEditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.minEditFieldLabel.Visible = 'off';
            app.minEditFieldLabel.Text = 'min';
            app.min_PCAEditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.min_PCAEditField.Limits = [1 Inf];
            app.min_PCAEditField.ValueChangedFcn = createCallbackFcn(app, @min_PCAEditFieldValueChanged, true);
            app.min_PCAEditField.Visible = 'off';
            app.min_PCAEditField.Value = 1;
            app.maxEditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.maxEditFieldLabel.Visible = 'off';
            app.maxEditFieldLabel.Text = 'max';
            app.max_PCAEditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.max_PCAEditField.Limits = [1 Inf];
            app.max_PCAEditField.ValueChangedFcn = createCallbackFcn(app, @max_PCAEditFieldValueChanged, true);
            app.max_PCAEditField.Visible = 'off';
            app.max_PCAEditField.Value = 16;
            app.EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {''};
            app.positioninfileSliderLabel = uilabel(app.ImagerenderingPanel);
            app.positioninfileSliderLabel.Text = {'position in file'; ''};
            app.positioninfileSlider = uislider(app.ImagerenderingPanel);
            app.positioninfileSlider.Limits = [0 1];
            app.positioninfileSlider.MajorTicks = [];
            app.positioninfileSlider.ValueChangedFcn = createCallbackFcn(app, @positioninfileSliderValueChanged, true);
            app.positioninfileSlider.MinorTicks = [];
            app.positioninfileSlider.Tooltip = {'Change value to display a different video timestamp in the gui.'};
            app.zretinaEditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.zretinaEditField.ValueChangedFcn = createCallbackFcn(app, @zretinaEditFieldValueChanged, true);
            app.zretinaEditField.Tooltip = {'Reconstruction distance in mm. Can be computed automatically with the ''Autofocus'' button.'};
            app.zretinaEditField.Value = 0.22;
            app.zirisEditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.zirisEditField.Limits = [0 Inf];
            app.zirisEditField.ValueChangedFcn = createCallbackFcn(app, @zirisEditFieldValueChanged, true);
            app.zirisEditField.Tooltip = {'Reconstruction distance in mm. Can be computed automatically with the ''Autofocus'' button.'};
            app.zirisEditField.Value = 0.22;
            app.Switch = uiswitch(app.ImagerenderingPanel, 'slider');
            app.Switch.Items = {'z_retina', 'z_iris'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @ZSwitchValueChanged, true);
            app.Switch.Value = 'z_retina';
            app.num_batches = uilabel(app.ImagerenderingPanel);
            app.num_batches.Text = '';
            app.batchsizeEditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.batchsizeEditFieldLabel.Text = 'batch size';
            app.batchsizeEditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.batchsizeEditField.Limits = [1 2048];
            app.batchsizeEditField.ValueChangedFcn = createCallbackFcn(app, @batchsizeEditFieldValueChanged, true);
            app.batchsizeEditField.Tooltip = {'Number of interferograms used to generate a single image. Increase value for better video quality.'};
            app.batchsizeEditField.Value = 512;
            app.RenderpreviewButton = uibutton(app.ImagerenderingPanel, 'push');
            app.RenderpreviewButton.ButtonPushedFcn = createCallbackFcn(app, @RenderpreviewButtonPushed, true);
            app.RenderpreviewButton.Text = 'Render preview';
            app.lowfrequencyCheckBox = uicheckbox(app.ImagerenderingPanel);
            app.lowfrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @lowfrequencyCheckBoxValueChanged, true);
            app.lowfrequencyCheckBox.Text = 'low frequency';
            app.renderLamp = uilamp(app.ImagerenderingPanel);
            app.SavepreviewButton = uibutton(app.ImagerenderingPanel, 'push');
            app.SavepreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SavePreview, true);
            app.SavepreviewButton.Text = 'Save preview';
            app.AutofocusButton = uibutton(app.ImagerenderingPanel, 'push');
            app.AutofocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutofocusButtonPushed, true);
            app.AutofocusButton.Text = 'Autofocus';
            app.savelamp = uilamp(app.ImagerenderingPanel);
            app.ImageLeft = uiimage(app.MainviewTab);
            app.ImageLeft.ImageClickedFcn = createCallbackFcn(app, @ImageLeftClicked, true);
            app.ImageRight = uiimage(app.MainviewTab);
            app.ImageRight.ImageClickedFcn = createCallbackFcn(app, @ImageRightClicked, true);
            % Show the figure after all components are created
            app.HolowavesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = holo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HolowavesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HolowavesUIFigure)
        end
    end
end